classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FuncaodeTreinoEditField         matlab.ui.control.EditField
        FuncaodeTreinoEditFieldLabel    matlab.ui.control.Label
        FunodeTreinoLabel               matlab.ui.control.Label
        GuardarRedesLabel               matlab.ui.control.Label
        ConfiguraesLabel                matlab.ui.control.Label
        SegmentaoLabel                  matlab.ui.control.Label
        NmerodeNeurniosporcamadaLabel   matlab.ui.control.Label
        FunesdeAtivaoLabel              matlab.ui.control.Label
        ExecutarTesteButton             matlab.ui.control.Button
        NomeGuardarMelhorRedeTesteEditField  matlab.ui.control.EditField
        NomeGuardarMelhorRedeTesteEditFieldLabel  matlab.ui.control.Label
        NomeGuardarMelhorRedeGlobalEditField  matlab.ui.control.EditField
        NomeGuardarMelhorRedeGlobalEditFieldLabel  matlab.ui.control.Label
        ValordeTesteEditField           matlab.ui.control.NumericEditField
        ValordeTesteEditFieldLabel      matlab.ui.control.Label
        ValordeValidacaoEditField       matlab.ui.control.NumericEditField
        ValordeValidacaoEditFieldLabel  matlab.ui.control.Label
        ValordeTreinamentoEditField     matlab.ui.control.NumericEditField
        ValordeTreinamentoEditFieldLabel  matlab.ui.control.Label
        CarregarFicheiroCSVButton       matlab.ui.control.Button
        NdeIteracoesEditField           matlab.ui.control.NumericEditField
        NdeIteracoesEditFieldLabel      matlab.ui.control.Label
        FuncaodeAtivacaonaCamadadeSaidaDropDown  matlab.ui.control.DropDown
        FuncaodeAtivacaonaCamadadeSaidaDropDownLabel  matlab.ui.control.Label
        FuncaodeAtivacao3CamadaEscondidaDropDown  matlab.ui.control.DropDown
        FuncaodeAtivacao3CamadaEscondidaDropDownLabel  matlab.ui.control.Label
        FuncaodeAtivacao2CamadaEscondidaDropDown  matlab.ui.control.DropDown
        FuncaodeAtivacao2CamadaEscondidaDropDownLabel  matlab.ui.control.Label
        FuncaodeAtivacao1CamadaEscondidaDropDown  matlab.ui.control.DropDown
        FuncaodeAtivacao1CamadaEscondidaDropDownLabel  matlab.ui.control.Label
        NdeNeuronios3CamadaEditField    matlab.ui.control.NumericEditField
        NdeNeuronios3CamadaEditFieldLabel  matlab.ui.control.Label
        NdeNeuronios2CamadaEditField    matlab.ui.control.NumericEditField
        NdeNeuronios2CamadaEditFieldLabel  matlab.ui.control.Label
        NdeNeuronios1CamadaEditField    matlab.ui.control.NumericEditField
        NdeNeuronios1CamadaEditFieldLabel  matlab.ui.control.Label
        NdeCamadasEscondidasEditField   matlab.ui.control.NumericEditField
        NdeCamadasEscondidasEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        
    end

    properties (Access = public)
    csvFilePath 
       end


    methods (Access = private)


% Função para verificar a soma dos valores
function checkSum(app)
   
    totalSum = app.ValordeTreinamentoEditField.Value + app.ValordeValidacaoEditField.Value + app.ValordeTesteEditField.Value;
    
    % Verificar se a soma é igual a 1
    if totalSum ~= 1
        
        uialert(app.UIFigure, 'A soma dos valores deve ser igual a 1.', 'Erro de Soma', 'Icon', 'error');
    end
end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NdeCamadasEscondidasEditField
        function NdeCamadasEscondidasEditFieldValueChanged(app, event)
            value = app.NdeCamadasEscondidasEditField.Value;
    
    % Desabilita todos os campos de neurônios e funções de ativação primeiro
    app.NdeNeuronios2CamadaEditField.Enable = 'off';
    app.NdeNeuronios3CamadaEditField.Enable = 'off';
    app.FuncaodeAtivacao2CamadaEscondidaDropDown.Enable = 'off';
    app.FuncaodeAtivacao3CamadaEscondidaDropDown.Enable = 'off';
    
    % Habilita campos baseado no número de camadas
    switch value
        case 1
            app.NdeNeuronios1CamadaEditField.Enable = 'on';
            app.NdeNeuronios2CamadaEditField.Enable = 'off';
            app.NdeNeuronios3CamadaEditField.Enable = 'off';
            app.FuncaodeAtivacao1CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao2CamadaEscondidaDropDown.Enable = 'off';
            app.FuncaodeAtivacao3CamadaEscondidaDropDown.Enable = 'off';
        case 2
            app.NdeNeuronios1CamadaEditField.Enable = 'on';
            app.NdeNeuronios2CamadaEditField.Enable = 'on';
            app.NdeNeuronios3CamadaEditField.Enable = 'off';
            app.FuncaodeAtivacao1CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao2CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao3CamadaEscondidaDropDown.Enable = 'off';
        case 3
            app.NdeNeuronios1CamadaEditField.Enable = 'on';
            app.NdeNeuronios2CamadaEditField.Enable = 'on';
            app.NdeNeuronios3CamadaEditField.Enable = 'on';
            app.FuncaodeAtivacao1CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao2CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao3CamadaEscondidaDropDown.Enable = 'on';
    end
        end

        % Value changed function: NdeNeuronios1CamadaEditField
        function NdeNeuronios1CamadaEditFieldValueChanged(app, event)
            value = app.NdeNeuronios1CamadaEditField.Value;
            
        end

        % Value changed function: NdeNeuronios2CamadaEditField
        function NdeNeuronios2CamadaEditFieldValueChanged(app, event)
            value = app.NdeNeuronios2CamadaEditField.Value;
            
        end

        % Value changed function: NdeNeuronios3CamadaEditField
        function NdeNeuronios3CamadaEditFieldValueChanged(app, event)
            value = app.NdeNeuronios3CamadaEditField.Value;
            
        end

        % Value changed function: FuncaodeAtivacao1CamadaEscondidaDropDown
        function FuncaodeAtivacao1CamadaEscondidaDropDownValueChanged(app, event)
            value = app.FuncaodeAtivacao1CamadaEscondidaDropDown.Value;
            
        end

        % Value changed function: FuncaodeAtivacao2CamadaEscondidaDropDown
        function FuncaodeAtivacao2CamadaEscondidaDropDownValueChanged(app, event)
            value = app.FuncaodeAtivacao2CamadaEscondidaDropDown.Value;
            
        end

        % Value changed function: FuncaodeAtivacao3CamadaEscondidaDropDown
        function FuncaodeAtivacao3CamadaEscondidaDropDownValueChanged(app, event)
            value = app.FuncaodeAtivacao3CamadaEscondidaDropDown.Value;
            
        end

        % Value changed function: FuncaodeAtivacaonaCamadadeSaidaDropDown
        function FuncaodeAtivacaonaCamadadeSaidaDropDownValueChanged(app, event)
            value = app.FuncaodeAtivacaonaCamadadeSaidaDropDown.Value;
            
        end

        % Value changed function: NdeIteracoesEditField
        function NdeIteracoesEditFieldValueChanged(app, event)
            value = app.NdeIteracoesEditField.Value;
            
        end

        % Button pushed function: CarregarFicheiroCSVButton
        function CarregarFicheiroCSVButtonPushed(app, event)

    [file, path] = uigetfile('*.csv', 'Selecione o arquivo CSV');
    if isequal(file, 0) || isequal(path, 0)
        disp('Usuário cancelou o carregamento');
        app.csvFilePath = '';  
    else
        app.csvFilePath = fullfile(path, file);
        disp(['Arquivo CSV carregado: ', app.csvFilePath]);
    end
        end

        % Value changed function: ValordeTreinamentoEditField
        function ValordeTreinamentoEditFieldValueChanged(app, event)
            value = app.ValordeTreinamentoEditField.Value;
            checkSum(app);
        end

        % Value changed function: ValordeValidacaoEditField
        function ValordeValidacaoEditFieldValueChanged(app, event)
            value = app.ValordeValidacaoEditField.Value;
            checkSum(app);
        end

        % Value changed function: ValordeTesteEditField
        function ValordeTesteEditFieldValueChanged(app, event)
            value = app.ValordeTesteEditField.Value;
            checkSum(app);
        end

        % Callback function
        function GuardarMelhorRedeGlobalButtonPushed(app, event)

        end

        % Callback function
        function GuardarMelhorRedeTesteButtonPushed(app, event)

        end

        % Value changed function: NomeGuardarMelhorRedeGlobalEditField
        function NomeGuardarMelhorRedeGlobalEditFieldValueChanged(app, event)
            value = app.NomeGuardarMelhorRedeGlobalEditField.Value;
            
        end

        % Value changed function: NomeGuardarMelhorRedeTesteEditField
        function NomeGuardarMelhorRedeTesteEditFieldValueChanged(app, event)
            value = app.NomeGuardarMelhorRedeTesteEditField.Value;
            
        end

        % Button pushed function: ExecutarTesteButton
        function ExecutarTesteButtonPushed(app, event)
            trainApp(app);
        end

        % Value changed function: FuncaodeTreinoEditField
        function FuncaodeTreinoEditFieldValueChanged(app, event)
            value = app.FuncaodeTreinoEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NdeCamadasEscondidasEditFieldLabel = uilabel(app.UIFigure);
            app.NdeCamadasEscondidasEditFieldLabel.Text = 'Nº de Camadas Escondidas';
            app.NdeCamadasEscondidasEditField = uieditfield(app.UIFigure, 'numeric');
            app.NdeCamadasEscondidasEditField.Limits = [1 3];
            app.NdeCamadasEscondidasEditField.AllowEmpty = 'on';
            app.NdeCamadasEscondidasEditField.ValueChangedFcn = createCallbackFcn(app, @NdeCamadasEscondidasEditFieldValueChanged, true);
            app.NdeCamadasEscondidasEditField.Value = [];
            app.NdeNeuronios1CamadaEditFieldLabel = uilabel(app.UIFigure);
            app.NdeNeuronios1CamadaEditFieldLabel.Text = 'Nº de Neuronios 1ª Camada';
            app.NdeNeuronios1CamadaEditField = uieditfield(app.UIFigure, 'numeric');
            app.NdeNeuronios1CamadaEditField.Limits = [1 50];
            app.NdeNeuronios1CamadaEditField.AllowEmpty = 'on';
            app.NdeNeuronios1CamadaEditField.ValueChangedFcn = createCallbackFcn(app, @NdeNeuronios1CamadaEditFieldValueChanged, true);
            app.NdeNeuronios1CamadaEditField.Value = [];
            app.NdeNeuronios2CamadaEditFieldLabel = uilabel(app.UIFigure);
            app.NdeNeuronios2CamadaEditFieldLabel.Text = 'Nº de Neuronios  2ª Camada';
            app.NdeNeuronios2CamadaEditField = uieditfield(app.UIFigure, 'numeric');
            app.NdeNeuronios2CamadaEditField.Limits = [1 50];
            app.NdeNeuronios2CamadaEditField.AllowEmpty = 'on';
            app.NdeNeuronios2CamadaEditField.ValueChangedFcn = createCallbackFcn(app, @NdeNeuronios2CamadaEditFieldValueChanged, true);
            app.NdeNeuronios2CamadaEditField.Value = [];
            app.NdeNeuronios3CamadaEditFieldLabel = uilabel(app.UIFigure);
            app.NdeNeuronios3CamadaEditFieldLabel.Text = 'Nº de Neuronios 3ª Camada';
            app.NdeNeuronios3CamadaEditField = uieditfield(app.UIFigure, 'numeric');
            app.NdeNeuronios3CamadaEditField.Limits = [1 50];
            app.NdeNeuronios3CamadaEditField.AllowEmpty = 'on';
            app.NdeNeuronios3CamadaEditField.ValueChangedFcn = createCallbackFcn(app, @NdeNeuronios3CamadaEditFieldValueChanged, true);
            app.NdeNeuronios3CamadaEditField.Value = [];
            app.FuncaodeAtivacao1CamadaEscondidaDropDownLabel = uilabel(app.UIFigure);
            app.FuncaodeAtivacao1CamadaEscondidaDropDownLabel.Text = 'Funcao de Ativacao 1ª Camada Escondida';
            app.FuncaodeAtivacao1CamadaEscondidaDropDown = uidropdown(app.UIFigure);
            app.FuncaodeAtivacao1CamadaEscondidaDropDown.Items = {'tansig', 'logsig'};
            app.FuncaodeAtivacao1CamadaEscondidaDropDown.ValueChangedFcn = createCallbackFcn(app, @FuncaodeAtivacao1CamadaEscondidaDropDownValueChanged, true);
            app.FuncaodeAtivacao1CamadaEscondidaDropDown.Value = 'tansig';
            app.FuncaodeAtivacao2CamadaEscondidaDropDownLabel = uilabel(app.UIFigure);
            app.FuncaodeAtivacao2CamadaEscondidaDropDownLabel.Text = 'Funcao de Ativacao 2ª Camada Escondida';
            app.FuncaodeAtivacao2CamadaEscondidaDropDown = uidropdown(app.UIFigure);
            app.FuncaodeAtivacao2CamadaEscondidaDropDown.Items = {'tansig', 'logsig'};
            app.FuncaodeAtivacao2CamadaEscondidaDropDown.ValueChangedFcn = createCallbackFcn(app, @FuncaodeAtivacao2CamadaEscondidaDropDownValueChanged, true);
            app.FuncaodeAtivacao2CamadaEscondidaDropDown.Value = 'tansig';
            app.FuncaodeAtivacao3CamadaEscondidaDropDownLabel = uilabel(app.UIFigure);
            app.FuncaodeAtivacao3CamadaEscondidaDropDownLabel.Text = 'Funcao de Ativacao 3ª Camada Escondida';
            app.FuncaodeAtivacao3CamadaEscondidaDropDown = uidropdown(app.UIFigure);
            app.FuncaodeAtivacao3CamadaEscondidaDropDown.Items = {'tansig', 'logsig'};
            app.FuncaodeAtivacao3CamadaEscondidaDropDown.ValueChangedFcn = createCallbackFcn(app, @FuncaodeAtivacao3CamadaEscondidaDropDownValueChanged, true);
            app.FuncaodeAtivacao3CamadaEscondidaDropDown.Value = 'tansig';
            app.FuncaodeAtivacaonaCamadadeSaidaDropDownLabel = uilabel(app.UIFigure);
            app.FuncaodeAtivacaonaCamadadeSaidaDropDownLabel.Text = 'Funcao de Ativacao na Camada de Saida';
            app.FuncaodeAtivacaonaCamadadeSaidaDropDown = uidropdown(app.UIFigure);
            app.FuncaodeAtivacaonaCamadadeSaidaDropDown.Items = {'purelin', 'logsig'};
            app.FuncaodeAtivacaonaCamadadeSaidaDropDown.ValueChangedFcn = createCallbackFcn(app, @FuncaodeAtivacaonaCamadadeSaidaDropDownValueChanged, true);
            app.FuncaodeAtivacaonaCamadadeSaidaDropDown.Value = 'purelin';
            app.NdeIteracoesEditFieldLabel = uilabel(app.UIFigure);
            app.NdeIteracoesEditFieldLabel.Text = 'Nº de Iteracoes';
            app.NdeIteracoesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NdeIteracoesEditField.Limits = [1 50];
            app.NdeIteracoesEditField.AllowEmpty = 'on';
            app.NdeIteracoesEditField.ValueChangedFcn = createCallbackFcn(app, @NdeIteracoesEditFieldValueChanged, true);
            app.NdeIteracoesEditField.Value = [];
            app.CarregarFicheiroCSVButton = uibutton(app.UIFigure, 'push');
            app.CarregarFicheiroCSVButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarFicheiroCSVButtonPushed, true);
            app.CarregarFicheiroCSVButton.Text = 'Carregar Ficheiro CSV';
            app.ValordeTreinamentoEditFieldLabel = uilabel(app.UIFigure);
            app.ValordeTreinamentoEditFieldLabel.Text = 'Valor de Treinamento';
            app.ValordeTreinamentoEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValordeTreinamentoEditField.Limits = [0.1 1];
            app.ValordeTreinamentoEditField.AllowEmpty = 'on';
            app.ValordeTreinamentoEditField.ValueChangedFcn = createCallbackFcn(app, @ValordeTreinamentoEditFieldValueChanged, true);
            app.ValordeTreinamentoEditField.Value = [];
            app.ValordeValidacaoEditFieldLabel = uilabel(app.UIFigure);
            app.ValordeValidacaoEditFieldLabel.Text = 'Valor de  Validacao';
            app.ValordeValidacaoEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValordeValidacaoEditField.Limits = [0.1 1];
            app.ValordeValidacaoEditField.AllowEmpty = 'on';
            app.ValordeValidacaoEditField.ValueChangedFcn = createCallbackFcn(app, @ValordeValidacaoEditFieldValueChanged, true);
            app.ValordeValidacaoEditField.Value = [];
            app.ValordeTesteEditFieldLabel = uilabel(app.UIFigure);
            app.ValordeTesteEditFieldLabel.Text = 'Valor de Teste';
            app.ValordeTesteEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValordeTesteEditField.Limits = [0.1 1];
            app.ValordeTesteEditField.AllowEmpty = 'on';
            app.ValordeTesteEditField.ValueChangedFcn = createCallbackFcn(app, @ValordeTesteEditFieldValueChanged, true);
            app.ValordeTesteEditField.Value = [];
            app.NomeGuardarMelhorRedeGlobalEditFieldLabel = uilabel(app.UIFigure);
            app.NomeGuardarMelhorRedeGlobalEditFieldLabel.Text = 'NomeGuardarMelhorRedeGlobal';
            app.NomeGuardarMelhorRedeGlobalEditField = uieditfield(app.UIFigure, 'text');
            app.NomeGuardarMelhorRedeGlobalEditField.CharacterLimits = [1 Inf];
            app.NomeGuardarMelhorRedeGlobalEditField.ValueChangedFcn = createCallbackFcn(app, @NomeGuardarMelhorRedeGlobalEditFieldValueChanged, true);
            app.NomeGuardarMelhorRedeTesteEditFieldLabel = uilabel(app.UIFigure);
            app.NomeGuardarMelhorRedeTesteEditFieldLabel.Text = 'NomeGuardarMelhorRedeTeste';
            app.NomeGuardarMelhorRedeTesteEditField = uieditfield(app.UIFigure, 'text');
            app.NomeGuardarMelhorRedeTesteEditField.CharacterLimits = [1 Inf];
            app.NomeGuardarMelhorRedeTesteEditField.ValueChangedFcn = createCallbackFcn(app, @NomeGuardarMelhorRedeTesteEditFieldValueChanged, true);
            app.ExecutarTesteButton = uibutton(app.UIFigure, 'push');
            app.ExecutarTesteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarTesteButtonPushed, true);
            app.ExecutarTesteButton.Text = 'Executar Teste';
            app.FunesdeAtivaoLabel = uilabel(app.UIFigure);
            app.FunesdeAtivaoLabel.Text = 'Funções de Ativação:';
            app.NmerodeNeurniosporcamadaLabel = uilabel(app.UIFigure);
            app.NmerodeNeurniosporcamadaLabel.Text = 'Número de Neurónios por camada:';
            app.SegmentaoLabel = uilabel(app.UIFigure);
            app.SegmentaoLabel.Text = 'Segmentação:';
            app.ConfiguraesLabel = uilabel(app.UIFigure);
            app.ConfiguraesLabel.Text = 'Configurações:';
            app.GuardarRedesLabel = uilabel(app.UIFigure);
            app.GuardarRedesLabel.Text = 'Guardar Redes:';
            app.FunodeTreinoLabel = uilabel(app.UIFigure);
            app.FunodeTreinoLabel.Text = 'Função de Treino:';
            app.FuncaodeTreinoEditFieldLabel = uilabel(app.UIFigure);
            app.FuncaodeTreinoEditFieldLabel.Text = 'Funcao de Treino';
            app.FuncaodeTreinoEditField = uieditfield(app.UIFigure, 'text');
            app.FuncaodeTreinoEditField.CharacterLimits = [1 Inf];
            app.FuncaodeTreinoEditField.ValueChangedFcn = createCallbackFcn(app, @FuncaodeTreinoEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end