classdef appTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NomedaRedeEditField        matlab.ui.control.EditField
        NomedaRedeEditFieldLabel   matlab.ui.control.Label
        RedeLabel                  matlab.ui.control.Label
        ExecutarTesteButton        matlab.ui.control.Button
        CarregarFicheiroCSVButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        
    end

    properties (Access = public)
    csvFilePath % Propriedade para guardar o path do CSV
       end


    methods (Access = private)


% Função para verificar a soma dos valores
function checkSum(app)
   
    totalSum = app.ValordeTreinamentoEditField.Value + app.ValordeValidacaoEditField.Value + app.ValordeTesteEditField.Value;
    
    % Verificar se a soma é igual a 1
    if totalSum ~= 1
        
        uialert(app.UIFigure, 'A soma dos valores deve ser igual a 1.', 'Erro de Soma', 'Icon', 'error');
    end
end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function NdeCamadasEscondidasEditFieldValueChanged(app, event)
            value = app.NdeCamadasEscondidasEditField.Value;
    
    % Desabilita todos os campos de neurônios e funções de ativação primeiro
    app.NdeNeuronios2CamadaEditField.Enable = 'off';
    app.NdeNeuronios3CamadaEditField.Enable = 'off';
    app.FuncaodeAtivacao2CamadaEscondidaDropDown.Enable = 'off';
    app.FuncaodeAtivacao3CamadaEscondidaDropDown.Enable = 'off';
    
    % Habilita campos baseado no número de camadas
    switch value
        case 1
            app.NdeNeuronios1CamadaEditField.Enable = 'on';
            app.NdeNeuronios2CamadaEditField.Enable = 'off';
            app.NdeNeuronios3CamadaEditField.Enable = 'off';
            app.FuncaodeAtivacao1CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao2CamadaEscondidaDropDown.Enable = 'off';
            app.FuncaodeAtivacao3CamadaEscondidaDropDown.Enable = 'off';
        case 2
            app.NdeNeuronios1CamadaEditField.Enable = 'on';
            app.NdeNeuronios2CamadaEditField.Enable = 'on';
            app.NdeNeuronios3CamadaEditField.Enable = 'off';
            app.FuncaodeAtivacao1CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao2CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao3CamadaEscondidaDropDown.Enable = 'off';
        case 3
            app.NdeNeuronios1CamadaEditField.Enable = 'on';
            app.NdeNeuronios2CamadaEditField.Enable = 'on';
            app.NdeNeuronios3CamadaEditField.Enable = 'on';
            app.FuncaodeAtivacao1CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao2CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao3CamadaEscondidaDropDown.Enable = 'on';
    end
        end

        % Button pushed function: CarregarFicheiroCSVButton
        function CarregarFicheiroCSVButtonPushed(app, event)
    
    [file, path] = uigetfile('*.csv', 'Selecione o arquivo CSV');
    if isequal(file, 0) || isequal(path, 0)
        disp('Usuário cancelou o carregamento');
        app.csvFilePath = '';  
    else
        app.csvFilePath = fullfile(path, file);  
        disp(['Arquivo CSV carregado: ', app.csvFilePath]);
    end
        end

        % Callback function
        function ValordeTreinamentoEditFieldValueChanged(app, event)
            value = app.ValordeTreinamentoEditField.Value;
            checkSum(app);
        end

        % Callback function
        function ValordeValidacaoEditFieldValueChanged(app, event)
            value = app.ValordeValidacaoEditField.Value;
            checkSum(app);
        end

        % Callback function
        function ValordeTesteEditFieldValueChanged(app, event)
            value = app.ValordeTesteEditField.Value;
            checkSum(app);
        end

        % Callback function
        function GuardarMelhorRedeGlobalButtonPushed(app, event)

        end

        % Callback function
        function GuardarMelhorRedeTesteButtonPushed(app, event)

        end

        % Button pushed function: ExecutarTesteButton
        function ExecutarTesteButtonPushed(app, event)
            test(app);
        end

        % Value changed function: NomedaRedeEditField
        function NomedaRedeEditFieldValueChanged(app, event)
            value = app.NomedaRedeEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CarregarFicheiroCSVButton = uibutton(app.UIFigure, 'push');
            app.CarregarFicheiroCSVButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarFicheiroCSVButtonPushed, true);
            app.CarregarFicheiroCSVButton.Text = 'Carregar Ficheiro CSV';
            app.ExecutarTesteButton = uibutton(app.UIFigure, 'push');
            app.ExecutarTesteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarTesteButtonPushed, true);
            app.ExecutarTesteButton.Text = 'Executar Teste';
            app.RedeLabel = uilabel(app.UIFigure);
            app.RedeLabel.Text = 'Rede:';
            app.NomedaRedeEditFieldLabel = uilabel(app.UIFigure);
            app.NomedaRedeEditFieldLabel.Text = 'Nome da Rede';
            app.NomedaRedeEditField = uieditfield(app.UIFigure, 'text');
            app.NomedaRedeEditField.CharacterLimits = [1 Inf];
            app.NomedaRedeEditField.ValueChangedFcn = createCallbackFcn(app, @NomedaRedeEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end