classdef Copy_of_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FuncaodeTreinoEditField       matlab.ui.control.EditField
        FuncaodeTreinoEditFieldLabel  matlab.ui.control.Label
        FunodeTreinoLabel             matlab.ui.control.Label
        ConfiguraesLabel              matlab.ui.control.Label
        ExecutarTesteButton           matlab.ui.control.Button
        CarregarFicheiroCSVButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        
    end

    properties (Access = public)
    csvFilePath % Propriedade para armazenar o caminho do arquivo CSV
       end


    methods (Access = private)


% Função para verificar a soma dos valores
function checkSum(app)
    % Somar os valores dos três campos de edição
    totalSum = app.ValordeTreinamentoEditField.Value + app.ValordeValidacaoEditField.Value + app.ValordeTesteEditField.Value;
    
    % Verificar se a soma é igual a 1
    if totalSum ~= 1
        % Exibir mensagem de erro se a soma não for igual a 1
        uialert(app.UIFigure, 'A soma dos valores deve ser igual a 1.', 'Erro de Soma', 'Icon', 'error');
    end
end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function NdeCamadasEscondidasEditFieldValueChanged(app, event)
            value = app.NdeCamadasEscondidasEditField.Value;
    
    % Desabilita todos os campos de neurônios e funções de ativação primeiro
    app.NdeNeuronios2CamadaEditField.Enable = 'off';
    app.NdeNeuronios3CamadaEditField.Enable = 'off';
    app.FuncaodeAtivacao2CamadaEscondidaDropDown.Enable = 'off';
    app.FuncaodeAtivacao3CamadaEscondidaDropDown.Enable = 'off';
    
    % Habilita campos baseado no número de camadas
    switch value
        case 1
            app.NdeNeuronios1CamadaEditField.Enable = 'on';
            app.NdeNeuronios2CamadaEditField.Enable = 'off';
            app.NdeNeuronios3CamadaEditField.Enable = 'off';
            app.FuncaodeAtivacao1CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao2CamadaEscondidaDropDown.Enable = 'off';
            app.FuncaodeAtivacao3CamadaEscondidaDropDown.Enable = 'off';
        case 2
            app.NdeNeuronios1CamadaEditField.Enable = 'on';
            app.NdeNeuronios2CamadaEditField.Enable = 'on';
            app.NdeNeuronios3CamadaEditField.Enable = 'off';
            app.FuncaodeAtivacao1CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao2CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao3CamadaEscondidaDropDown.Enable = 'off';
        case 3
            app.NdeNeuronios1CamadaEditField.Enable = 'on';
            app.NdeNeuronios2CamadaEditField.Enable = 'on';
            app.NdeNeuronios3CamadaEditField.Enable = 'on';
            app.FuncaodeAtivacao1CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao2CamadaEscondidaDropDown.Enable = 'on';
            app.FuncaodeAtivacao3CamadaEscondidaDropDown.Enable = 'on';
    end
        end

        % Button pushed function: CarregarFicheiroCSVButton
        function CarregarFicheiroCSVButtonPushed(app, event)
     % Abre o diálogo de seleção de arquivo
    [file, path] = uigetfile('*.csv', 'Selecione o arquivo CSV');
    if isequal(file, 0) || isequal(path, 0)
        disp('Usuário cancelou o carregamento');
        app.csvFilePath = '';  % Assegura que nenhum caminho é armazenado se cancelado
    else
        app.csvFilePath = fullfile(path, file);  % Armazena o caminho completo do arquivo
        disp(['Arquivo CSV carregado: ', app.csvFilePath]);
    end
        end

        % Callback function: not associated with a component
        function ValordeTreinamentoEditFieldValueChanged(app, event)
            value = app.ValordeTreinamentoEditField.Value;
            checkSum(app);
        end

        % Callback function: not associated with a component
        function ValordeValidacaoEditFieldValueChanged(app, event)
            value = app.ValordeValidacaoEditField.Value;
            checkSum(app);
        end

        % Callback function: not associated with a component
        function ValordeTesteEditFieldValueChanged(app, event)
            value = app.ValordeTesteEditField.Value;
            checkSum(app);
        end

        % Callback function
        function GuardarMelhorRedeGlobalButtonPushed(app, event)

        end

        % Callback function
        function GuardarMelhorRedeTesteButtonPushed(app, event)

        end

        % Button pushed function: ExecutarTesteButton
        function ExecutarTesteButtonPushed(app, event)
            testApp(app);
        end

        % Value changed function: FuncaodeTreinoEditField
        function FuncaodeTreinoEditFieldValueChanged(app, event)
            value = app.FuncaodeTreinoEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CarregarFicheiroCSVButton = uibutton(app.UIFigure, 'push');
            app.CarregarFicheiroCSVButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarFicheiroCSVButtonPushed, true);
            app.CarregarFicheiroCSVButton.Text = 'Carregar Ficheiro CSV';
            app.ExecutarTesteButton = uibutton(app.UIFigure, 'push');
            app.ExecutarTesteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarTesteButtonPushed, true);
            app.ExecutarTesteButton.Text = 'Executar Teste';
            app.ConfiguraesLabel = uilabel(app.UIFigure);
            app.ConfiguraesLabel.Text = 'Configurações:';
            app.FunodeTreinoLabel = uilabel(app.UIFigure);
            app.FunodeTreinoLabel.Text = 'Função de Treino:';
            app.FuncaodeTreinoEditFieldLabel = uilabel(app.UIFigure);
            app.FuncaodeTreinoEditFieldLabel.Text = 'Funcao de Treino';
            app.FuncaodeTreinoEditField = uieditfield(app.UIFigure, 'text');
            app.FuncaodeTreinoEditField.CharacterLimits = [1 Inf];
            app.FuncaodeTreinoEditField.ValueChangedFcn = createCallbackFcn(app, @FuncaodeTreinoEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end